classdef MarkEventsVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ST_JNKPressjTextArea          matlab.ui.control.TextArea
        ST_JNKPressjLabel             matlab.ui.control.Label
        Label_ST_JNK                  matlab.ui.control.Label
        CorrectButton_ST_JNK          matlab.ui.control.Button
        END_JNKPresskTextArea         matlab.ui.control.TextArea
        END_JNKPresskTextAreaLabel    matlab.ui.control.Label
        Label_END_JNK                 matlab.ui.control.Label
        CorrectButton_END_JNK         matlab.ui.control.Button
        ST_HND_TO_MTHPress6TextArea   matlab.ui.control.TextArea
        ST_HND_TO_MTHPress6TextAreaLabel  matlab.ui.control.Label
        Label_ST_HND_TO_MTH           matlab.ui.control.Label
        CorrectButton_ST_HND_TO_MTH   matlab.ui.control.Button
        END_HND_TO_MTHPress7TextArea  matlab.ui.control.TextArea
        END_HND_TO_MTHPress7TextAreaLabel  matlab.ui.control.Label
        Label_END_HND_TO_MTH          matlab.ui.control.Label
        CorrectButton_END_HND_TO_MTH  matlab.ui.control.Button
        OTHERPress8TextArea           matlab.ui.control.TextArea
        OTHERPress8TextAreaLabel      matlab.ui.control.Label
        Label_OTHER                   matlab.ui.control.Label
        CorrectButton_OTHER           matlab.ui.control.Button
        ZoomButton                    matlab.ui.control.StateButton
        Label_END_RLS                 matlab.ui.control.Label
        Label_END_PULL                matlab.ui.control.Label
        Label_ST_PULL                 matlab.ui.control.Label
        Label_HND_AT_OBJ              matlab.ui.control.Label
        Label_ST_RCH                  matlab.ui.control.Label
        JumptoFrameEditField          matlab.ui.control.NumericEditField
        JumptoFrameEditFieldLabel     matlab.ui.control.Label
        CorrectButton_END_RLS         matlab.ui.control.Button
        CorrectButton_END_PULL        matlab.ui.control.Button
        CorrectButton_ST_PULL         matlab.ui.control.Button
        CorrectButton_HND_AT_OBJ      matlab.ui.control.Button
        CorrectButton_ST_RCH          matlab.ui.control.Button
        MarkEventsButton              matlab.ui.control.StateButton
        SaveEventsButton              matlab.ui.control.Button
        INSTRUCTIONS_HEADER_TEXT      matlab.ui.control.Label
        PlayButton                    matlab.ui.control.StateButton
        END_PULLPress4TextArea        matlab.ui.control.TextArea
        END_PULLPress4TextAreaLabel   matlab.ui.control.Label
        END_RLSPress5TextArea         matlab.ui.control.TextArea
        RLSPress5Label                matlab.ui.control.Label
        ST_PULLPress3TextArea         matlab.ui.control.TextArea
        ST_PULLPress3Label            matlab.ui.control.Label
        HND_AT_OBJPress2TextArea      matlab.ui.control.TextArea
        HND_AT_OBJPress2Label         matlab.ui.control.Label
        ST_RCHPress1TextArea          matlab.ui.control.TextArea
        ST_RCHPress1TextAreaLabel     matlab.ui.control.Label
        LoadVideosButton              matlab.ui.control.Button
        UIAxes_Vicon                  matlab.ui.control.UIAxes
        UIAxes_VideoR                 matlab.ui.control.UIAxes
        UIAxes_VideoL                 matlab.ui.control.UIAxes
    end

    
properties (Access = private)
    vidObj1 % Video object for the first video
    vidObj2 % Video object for the second video
    PathSession % Path for the session data
    PathProcessed % Path for the processed data
    N_frames % Number of frames in the video
    N % Number of frames to load at once
    brightLev % Brightness level for displaying images
    filename % Filename for saving or loading data
    eventMarked % Indicator for events marked in the video
    i % Index for loading frames
    fr % Frame number within the current batch
    start_frame % Starting frame for processing
    cnt_frame % Counter for the current frame
    video1 % Data for the first video
    video2 % Data for the second video
    pick_index % Index for picking a specific frame or event
    pickPosition % Position for picking a specific frame or event
    h_cntFrame % Handle for the current frame counter visualization
    vidFrameRate % Frame rate of the video
    kinFrameRate % Frame rate of the kinematic data
    ST_RCH % Start Reach (1)
    HND_AT_OBJ % Hand at Object (2)
    ST_PULL % Start Pull (3)
    END_PULL % End Pull (4)
    END_RLS % Release (5)
    ST_HND_TO_MTH % Start Hand to Mouth (6)
    END_HND_TO_MTH % End Hand to Mouth (7)
    OTHER % Other or Miscellaneous Event (8)
    ST_JNK % Start Junk Data (j)
    END_JNK % End Junk Data (J)
    dlc_kin_mat_video1 % DLC kinematic data for video 1
    dlc_kin_mat_video2 % DLC kinematic data for video 2
    selected_bodypart_video1 % Selected body part to plot from video 1
    selected_bodypart_video2 % Selected body part to plot from video 2
    outputPath % Path to output events, set to path of DLC mat files
end

methods (Access = private)

    function update_image(app)
        % Check if the current frame counter exceeds the current batch of frames
        if app.cnt_frame > app.i * app.N
            app.i = app.i + 1; % Increment the batch index
            fprintf('Loading frames %d to %d for video 1\n', 1 + (app.i-1) * app.N, app.i * app.N)
            % Load frames for video 1
            app.video1 = read(app.vidObj1, [1 + (app.i-1) * app.N, min(app.i * app.N, app.N_frames)]);
            
            fprintf('Loading frames %d to %d for video 2\n', 1 + (app.i-1) * app.N, app.i * app.N)
            % Load frames for video 2
            app.video2 = read(app.vidObj2, [1 + (app.i-1) * app.N, min(app.i * app.N, app.N_frames)]);
        elseif app.cnt_frame <= (app.i-1) * app.N
            app.i = app.i - 1; % Decrement the batch index
            fprintf('Loading frames %d to %d for video 1\n', 1 + (app.i-1) * app.N, app.i * app.N)
            % Load frames for video 1
            app.video1 = read(app.vidObj1, [1 + (app.i-1) * app.N, min(app.i * app.N, app.N_frames)]);
            
            fprintf('Loading frames %d to %d for video 2\n', 1 + (app.i-1) * app.N, app.i * app.N)
            % Load frames for video 2
            app.video2 = read(app.vidObj2, [1 + (app.i-1) * app.N, min(app.i * app.N, app.N_frames)]);
        end
        app.fr = app.cnt_frame - (app.i-1) * app.N; % Calculate the current frame within the batch
        
        % Extract 2D coordinates of the selected bodypart for both videos
        bodypart_coords_v1 = app.dlc_kin_mat_video1.(app.selected_bodypart_video1);
        x1 = bodypart_coords_v1.x(app.cnt_frame);
        y1 = bodypart_coords_v1.y(app.cnt_frame);
        
        bodypart_coords_v2 = app.dlc_kin_mat_video2.(app.selected_bodypart_video2);
        x2 = bodypart_coords_v2.x(app.cnt_frame);
        y2 = bodypart_coords_v2.y(app.cnt_frame);
        
        % Display the current frame of video 1 with adjusted brightness
        imshow(app.video1(:,:,:,app.fr) * app.brightLev, 'Parent', app.UIAxes_VideoL);
        hold(app.UIAxes_VideoL, 'on');
        plot(app.UIAxes_VideoL, x1, y1, 'ro', 'MarkerSize', 5, 'MarkerFaceColor', 'r'); % Plot the bodypart as a red dot
        hold(app.UIAxes_VideoL, 'off');
        app.UIAxes_VideoL.Title.String = sprintf('Left - frame %d / %d', app.cnt_frame, app.N_frames);
        
        % Display the current frame of video 2 with adjusted brightness
        imshow(app.video2(:,:,:,app.fr) * app.brightLev, 'Parent', app.UIAxes_VideoR);
        hold(app.UIAxes_VideoR, 'on');
        plot(app.UIAxes_VideoR, x2, y2, 'ro', 'MarkerSize', 5, 'MarkerFaceColor', 'r'); % Plot the bodypart as a red dot
        hold(app.UIAxes_VideoR, 'off');
        app.UIAxes_VideoR.Title.String = sprintf('Right - frame %d / %d', app.cnt_frame, app.N_frames);
        
        % Update the position of the frame counter visualization
        set(app.h_cntFrame, 'XData', app.cnt_frame * [1 1])
    end
end

methods (Access = public)
    
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc  % Clear the command window
            
            % Display a message to instruct the user to load the videos
            disp('Please Load the videos')
            
            % Initialize the starting frame for processing
            app.start_frame = 1;
            
            % Set the current frame counter to the starting frame
            app.cnt_frame = app.start_frame;
            
            % Initialize the index for picking a specific frame or event
            app.pick_index = 0;
        end

        % Button pushed function: LoadVideosButton
        function LoadVideosButtonPushed(app, event)
            % Read video files
            [filenm, path, FilterIndex] = uigetfile('*','Select the 1st video file');
            if FilterIndex == 0
                disp('Error in the video file loading') % Display error if no file is selected
                return
            else
                disp('1st video file has been successfully loaded') % Display success message
            end
            addpath(path);
            app.vidObj1 = VideoReader(filenm); % Create a VideoReader object for the first video
            app.vidFrameRate = app.vidObj1.FrameRate; % Get the frame rate of the first video
            
            % Read the second video file
            [filenm, path, FilterIndex] = uigetfile('*','Select the 2nd video file');
            if FilterIndex == 0
                disp('Error in the video file loading') % Display error if no file is selected
                return
            else
                disp('2nd video file has been successfully loaded') % Display success message
            end
            addpath(path);
            app.vidObj2 = VideoReader(filenm); % Create a VideoReader object for the second video

            % NEW: LOAD DLC KIN MAT FILES AND SELECT BODYPARTS TO PLOT

            % Prompt and load dlc_kin_mat_video1 file
            [filenm, path, FilterIndex] = uigetfile('*','Select the DLC kin mat file for video 1');
            if FilterIndex == 0
                disp('Error in the DLC kin mat file loading for video 1') % Display error if no file is selected
                return
            else
                disp('DLC kin mat file for video 1 has been successfully loaded') % Display success message
            end
            addpath(path);
            app.dlc_kin_mat_video1 = load(filenm); % Load the DLC kin mat file for video 1
        
            % Prompt and load dlc_kin_mat_video2 file
            [filenm, path, FilterIndex] = uigetfile('*','Select the DLC kin mat file for video 2');
            if FilterIndex == 0
                disp('Error in the DLC kin mat file loading for video 2') % Display error if no file is selected
                return
            else
                disp('DLC kin mat file for video 2 has been successfully loaded') % Display success message
            end
            addpath(path);
            app.dlc_kin_mat_video2 = load(filenm); % Load the DLC kin mat file for video 2
            app.outputPath = path;
        
            % Prompt for the bodypart to plot from video 1
            bodyparts_video1 = fieldnames(app.dlc_kin_mat_video1); % Get bodyparts from the struct
            [indx, tf] = listdlg('PromptString', {'Select a bodypart to plot from video 1:'}, ...
                                 'SelectionMode', 'single', 'ListString', bodyparts_video1);
            if tf == 0
                disp('No bodypart selected for video 1') % Display error if no bodypart is selected
                return
            else
                app.selected_bodypart_video1 = bodyparts_video1{indx}; % Store the selected bodypart
                disp(['Selected bodypart for video 1: ', app.selected_bodypart_video1]) % Display success message
            end
        
            % Prompt for the bodypart to plot from video 2
            bodyparts_video2 = fieldnames(app.dlc_kin_mat_video2); % Get bodyparts from the struct
            [indx, tf] = listdlg('PromptString', {'Select a bodypart to plot from video 2:'}, ...
                                 'SelectionMode', 'single', 'ListString', bodyparts_video2);
            if tf == 0
                disp('No bodypart selected for video 2') % Display error if no bodypart is selected
                return
            else
                app.selected_bodypart_video2 = bodyparts_video2{indx}; % Store the selected bodypart
                disp(['Selected bodypart for video 2: ', app.selected_bodypart_video2]) % Display success message
            end

            % END DLC KIN MAT FILE AND BODYPART LOGIC
            
            % Set the paths for the session and processed data
            app.PathSession = pwd; % Path to the current working directory
            app.PathProcessed = fullfile(app.PathSession, 'ProcessedData'); % Path to the processed data
            
            % Create the processed data directory if it does not exist
            if ~exist(app.PathProcessed, 'dir')
                mkdir(app.PathProcessed);
            end
            
            % Calculate the number of frames in the video
            app.N_frames = floor(app.vidObj1.Duration * app.vidObj1.FrameRate);
            app.N = 14900; % Number of frames loaded at once
            
            app.brightLev = 1; % Set the brightness level
            app.filename = filenm(1:end-4); % Set the filename without extension
            
            % Display the first frame images
            app.i = 1; % Initialize the frame batch index
            app.fr = 1; % Initialize the frame index within the batch
            
            % Load and display frames for video 1
            fprintf('Loading frames %d to %d for video 1\n', 1 + (app.i-1) * app.N, app.i * app.N);     % Display frames loaded in current batch (N)
            app.video1 = read(app.vidObj1, [1 + (app.i-1) * app.N, min(app.i * app.N, app.N_frames)]);  % Load current length-N batch of frames into video1
            imshow(app.video1(:,:,:,app.fr) * app.brightLev, 'Parent', app.UIAxes_VideoL);  
            app.UIAxes_VideoL.Title.String = sprintf('Left - frame %d', app.fr);
            
            % Load and display frames for video 2
            fprintf('Loading frames %d to %d for video 2\n', 1 + (app.i-1) * app.N, app.i * app.N)
            app.video2 = read(app.vidObj2, [1 + (app.i-1) * app.N, min(app.i * app.N, app.N_frames)]);
            imshow(app.video2(:,:,:,app.fr) * app.brightLev, 'Parent', app.UIAxes_VideoR);
            app.UIAxes_VideoR.Title.String = sprintf('Right - frame %d', app.fr);
            
            % BEGIN NEW PLOTTING LOGIC: PROMPT AXIS TO PLOT, PLOT DLC DATA
            % IN CORNER FIGURE

            % Prompt the user for the y-axis signal to plot (x, y, z)
            signal_options = {'x', 'y', 'z'};
            [indx, tf] = listdlg('PromptString', {'Select the y-axis signal to plot:'}, ...
                                 'SelectionMode', 'single', 'ListString', signal_options);
            if tf == 0
                disp('No signal selected') % Display error if no signal is selected
                return
            else
                selected_signal = signal_options{indx}; % Store the selected signal
                disp(['Selected signal to plot: ', selected_signal]) % Display success message
            end
            
            % Extract the selected signal for the selected body parts
            signal_video1 = app.dlc_kin_mat_video1.(app.selected_bodypart_video1).(selected_signal);
            signal_video2 = app.dlc_kin_mat_video2.(app.selected_bodypart_video2).(selected_signal);
            
            % Determine the length of the longer signal
            max_length = max(length(signal_video1), length(signal_video2));
            
            % Pad the shorter signal with NaNs
            if length(signal_video1) < max_length
                signal_video1 = [signal_video1; NaN(max_length - length(signal_video1), 1)];
            end
            if length(signal_video2) < max_length
                signal_video2 = [signal_video2; NaN(max_length - length(signal_video2), 1)];
            end
            
            % Set x-axis data based on frame index
            x_axis_data = 1:max_length;
            
            % Plot the selected signal for the body parts
            hold(app.UIAxes_Vicon, 'on') % Hold the current plot
            plot(app.UIAxes_Vicon, x_axis_data, signal_video1, '-b') % Plot signal of body part from video 1
            plot(app.UIAxes_Vicon, x_axis_data, signal_video2, '-r') % Plot signal of body part from video 2
            
            % Plot current frame indicator
            app.h_cntFrame = plot(app.UIAxes_Vicon, app.cnt_frame * [1 1], ...
                [nanmin([signal_video1; signal_video2]), nanmax([signal_video1; signal_video2])], ':k');
            
            % Set y-axis limits
            set(app.UIAxes_Vicon, 'ylim', [nanmin([signal_video1; signal_video2]), nanmax([signal_video1; signal_video2])]);

            % END NEW PLOTTING LOGIC
            
            % Reset or load events
            backupEvent = [app.PathProcessed app.filename '_events.mat'];
            if exist(backupEvent, 'file') ~= 0
                choice = questdlg('An events file with the same name already exists, what do you want to do?', ...
                                  'There is already a file for this acquisition', ...
                                  'Start from scratch', 'Start with those previous events', 'Start from scratch');
                % Handle response
                switch choice
                    case 'Start with those previous events'
                        load(backupEvent, dataEvent); % Load previous events
                        disp('Previous events have been loaded')
                        % LEGACY
                        % app.eventMarked.LFO = dataEvent.LFO;
                        % app.eventMarked.LFS = dataEvent.LFS;
                        % app.eventMarked.RFO = dataEvent.RFO;
                        % app.eventMarked.OTHER = dataEvent.other;
                        % app.eventMarked.RFS = dataEvent.RFS;

                        app.ST_RCH            = dataEvent.ST_RCH;           % Start Reach (1)
                        app.HND_AT_OBJ        = dataEvent.HND_AT_OBJ;       % Hand at Object (2)
                        app.ST_PULL           = dataEvent.ST_PULL;          % Start Pull (3)
                        app.END_PULL          = dataEvent.END_PULL;         % End Pull (4)
                        app.END_RLS           = dataEvent.END_RLS;          % Release (5)
                        app.ST_HND_TO_MTH    = dataEvent.ST_HND_TO_MTH;   % Start Hand to Mouth (6)
                        app.END_HND_TO_MTH   = dataEvent.END_HND_TO_MTH;  % End Hand to Mouth (7)
                        app.OTHER             = dataEvent.OTHER;            % Other or Miscellaneous Event (8)
                        app.ST_JNK            = dataEvent.ST_JNK;           % Start Junk Data (j)
                        app.END_JNK           = dataEvent.END_JNK;          % End Junk Data (J)
                    case 'Start from scratch'
                        % LEGACY
                        % % Initialize empty events
                        % app.eventMarked.LFO = []; % Beginning of movement
                        % app.eventMarked.LFS = []; % Grasping
                        % app.eventMarked.RFO = []; % Light off or time mark leaving the object
                        % app.eventMarked.OTHER = []; % Successful trial indicator
                        % app.eventMarked.RFS = [];

                        app.ST_RCH            = []; % Start Reach (1)
                        app.HND_AT_OBJ        = []; % Hand at Object (2)
                        app.ST_PULL           = []; % Start Pull (3)
                        app.END_PULL          = []; % End Pull (4)
                        app.END_RLS           = []; % Release (5)
                        app.ST_HND_TO_MTH    = []; % Start Hand to Mouth (6)
                        app.END_HND_TO_MTH   = []; % End Hand to Mouth (7)
                        app.OTHER             = []; % Other or Miscellaneous Event (8)
                        app.ST_JNK            = [];           % Start Junk Data (j)
                        app.END_JNK           = [];          % End Junk Data (J)
                end
            else
                
                % LEGACY
                % % Initialize empty events
                % app.eventMarked.LFO = []; % Beginning of movement
                % app.eventMarked.LFS = []; % Grasping
                % app.eventMarked.RFO = []; % Light off or time mark leaving the object
                % app.eventMarked.OTHER = []; % Successful trial indicator
                % app.eventMarked.RFS = [];

                app.ST_RCH            = []; % Start Reach (1)
                app.HND_AT_OBJ        = []; % Hand at Object (2)
                app.ST_PULL           = []; % Start Pull (3)
                app.END_PULL          = []; % End Pull (4)
                app.END_RLS           = []; % Release (5)
                app.ST_HND_TO_MTH    = []; % Start Hand to Mouth (6)
                app.END_HND_TO_MTH   = []; % End Hand to Mouth (7)
                app.OTHER             = []; % Other or Miscellaneous Event (8)
                app.ST_JNK            = [];           % Start Junk Data (j)
                app.END_JNK           = [];          % End Junk Data (J)
            end
            
            % Update event text areas and labels
            app.ST_RCHPress1TextArea.Value = num2str(app.ST_RCH);
            app.Label_ST_RCH.Text = sprintf('(%d)', length(app.ST_RCH));
            
            app.HND_AT_OBJPress2TextArea.Value = num2str(app.HND_AT_OBJ);
            app.Label_HND_AT_OBJ.Text = sprintf('(%d)', length(app.HND_AT_OBJ));
            
            app.ST_PULLPress3TextArea.Value = num2str(app.ST_PULL);
            app.Label_ST_PULL.Text = sprintf('(%d)', length(app.ST_PULL));
            
            app.END_PULLPress4TextArea.Value = num2str(app.END_PULL);
            app.Label_END_PULL.Text = sprintf('(%d)', length(app.END_PULL));
            
            app.END_RLSPress5TextArea.Value = num2str(app.END_RLS);
            app.Label_END_RLS.Text = sprintf('(%d)', length(app.END_RLS));
            
            app.ST_HND_TO_MTHPress6TextArea.Value = num2str(app.ST_HND_TO_MTH);
            app.Label_ST_HND_TO_MTH.Text = sprintf('(%d)', length(app.ST_HND_TO_MTH));
            
            app.END_HND_TO_MTHPress7TextArea.Value = num2str(app.END_HND_TO_MTH);
            app.Label_END_HND_TO_MTH.Text = sprintf('(%d)', length(app.END_HND_TO_MTH));
            
            app.OTHERPress8TextArea.Value = num2str(app.OTHER);
            app.Label_OTHER.Text = sprintf('(%d)', length(app.OTHER));

            app.ST_JNKPressjTextArea.Value = num2str(app.ST_JNK);
            app.Label_ST_JNK.Text = sprintf('(%d)', length(app.ST_JNK));

            app.END_JNKPresskTextArea.Value = num2str(app.END_JNK);
            app.Label_END_JNK.Text = sprintf('(%d)', length(app.END_JNK));
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value == 1
                disp('Start to play the videos, press to stop')
                app.PlayButton.Text = 'STOP Play';
                while value && app.cnt_frame<=app.N_frames
                    app.cnt_frame = app.cnt_frame+1;
                    app.update_image;
                    
                    value = app.PlayButton.Value;
                    pause(0.5)
                end
            else
                disp('Stopped the videos, press to start')
                app.PlayButton.Text = 'Play';
            end
        end

        % Value changed function: MarkEventsButton
        function MarkEventsButtonValueChanged(app, event)
            value = app.MarkEventsButton.Value;
            
            if value
                disp('Start changing image frame with keyboard')
                
            else
                disp('Stop changing image frame with keyboard')
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;  % Get the key that was pressed
            if app.MarkEventsButton.Value  % Check if the Mark Events button is enabled
                switch key
                    case 'leftarrow'  % If the left arrow key is pressed
                        app.cnt_frame = app.cnt_frame - 1;  % Decrement the current frame by 1
                        disp('leftarrow');
                    case 'rightarrow'  % If the right arrow key is pressed
                        app.cnt_frame = app.cnt_frame + 1;  % Increment the current frame by 1
                        disp('rightarrow');
                    case 'downarrow'  % If the down arrow key is pressed
                        app.cnt_frame = app.cnt_frame - 10;  % Decrement the current frame by 10
                        disp('downarrow');
                    case 'uparrow'  % If the up arrow key is pressed
                        app.cnt_frame = app.cnt_frame + 10;  % Increment the current frame by 10
                        disp('uparrow');
                    case '9'  % If the '9' key is pressed
                        app.cnt_frame = app.cnt_frame + 100;  % Increment the current frame by 100
                        disp('9');
                    case '0'  % If the '0' key is pressed
                        app.cnt_frame = app.cnt_frame - 100;  % Decrement the current frame by 100
                        disp('0');
            
                    % Cases for marking specific events
                    case '1'  % If the '1' key is pressed
                        app.ST_RCH = [app.ST_RCH app.cnt_frame];  % Add the current frame to ST_RCH
                        app.ST_RCH = sort(app.ST_RCH, 'ascend');  % Sort ST_RCH in ascending order
                        app.ST_RCHPress1TextArea.Value = num2str(app.ST_RCH);  % Update the text area value
                        fprintf('%s - current frame: %d\n', key, app.cnt_frame);  % Print the key and current frame
            
                        app.Label_ST_RCH.Text = sprintf('(%d)', length(app.ST_RCH));  % Update the label text
                    case '2'  % If the '2' key is pressed
                        app.HND_AT_OBJ = [app.HND_AT_OBJ app.cnt_frame];  % Add the current frame to HND_AT_OBJ
                        app.HND_AT_OBJ = sort(app.HND_AT_OBJ, 'ascend');  % Sort HND_AT_OBJ in ascending order
                        app.HND_AT_OBJPress2TextArea.Value = num2str(app.HND_AT_OBJ);  % Update the text area value
                        fprintf('%s - current frame: %d\n', key, app.cnt_frame);  % Print the key and current frame
            
                        app.Label_HND_AT_OBJ.Text = sprintf('(%d)', length(app.HND_AT_OBJ));  % Update the label text
                    case '3'  % If the '3' key is pressed
                        app.ST_PULL = [app.ST_PULL app.cnt_frame];  % Add the current frame to ST_PULL
                        app.ST_PULL = sort(app.ST_PULL, 'ascend');  % Sort ST_PULL in ascending order
                        app.ST_PULLPress3TextArea.Value = num2str(app.ST_PULL);  % Update the text area value
                        fprintf('%s - current frame: %d\n', key, app.cnt_frame);  % Print the key and current frame
            
                        app.Label_ST_PULL.Text = sprintf('(%d)', length(app.ST_PULL));  % Update the label text
                    case '4'  % If the '4' key is pressed
                        app.END_PULL = [app.END_PULL app.cnt_frame];  % Add the current frame to END_PULL
                        app.END_PULL = sort(app.END_PULL, 'ascend');  % Sort END_PULL in ascending order
                        app.END_PULLPress4TextArea.Value = num2str(app.END_PULL);  % Update the text area value
                        fprintf('%s - current frame: %d\n', key, app.cnt_frame);  % Print the key and current frame
            
                        app.Label_END_PULL.Text = sprintf('(%d)', length(app.END_PULL));  % Update the label text
                    case '5'  % If the '5' key is pressed
                        app.END_RLS = [app.END_RLS app.cnt_frame];  % Add the current frame to END_RLS
                        app.END_RLS = sort(app.END_RLS, 'ascend');  % Sort END_RLS in ascending order
                        app.END_RLSPress5TextArea.Value = num2str(app.END_RLS);  % Update the text area value
                        fprintf('%s - current frame: %d\n', key, app.cnt_frame);  % Print the key and current frame
            
                        app.Label_END_RLS.Text = sprintf('(%d)', length(app.END_RLS));  % Update the label text
                    case '6'  % If the '6' key is pressed
                        app.ST_HND_TO_MTH = [app.ST_HND_TO_MTH app.cnt_frame];  % Add the current frame to ST_HND_TO_MTH
                        app.ST_HND_TO_MTH = sort(app.ST_HND_TO_MTH, 'ascend');  % Sort ST_HND_TO_MTH in ascending order
                        app.ST_HND_TO_MTHPress6TextArea.Value = num2str(app.ST_HND_TO_MTH);  % Update the text area value
                        fprintf('%s - current frame: %d\n', key, app.cnt_frame);  % Print the key and current frame
            
                        app.Label_ST_HND_TO_MTH.Text = sprintf('(%d)', length(app.ST_HND_TO_MTH));  % Update the label text
                    case '7'  % If the '7' key is pressed
                        app.END_HND_TO_MTH = [app.END_HND_TO_MTH app.cnt_frame];  % Add the current frame to END_HND_TO_MTH
                        app.END_HND_TO_MTH = sort(app.END_HND_TO_MTH, 'ascend');  % Sort END_HND_TO_MTH in ascending order
                        app.END_HND_TO_MTHPress7TextArea.Value = num2str(app.END_HND_TO_MTH);  % Update the text area value
                        fprintf('%s - current frame: %d\n', key, app.cnt_frame);  % Print the key and current frame
            
                        app.Label_END_HND_TO_MTH.Text = sprintf('(%d)', length(app.END_HND_TO_MTH));  % Update the label text
                    case '8'  % If the '8' key is pressed
                        app.OTHER = [app.OTHER app.cnt_frame];  % Add the current frame to OTHER
                        app.OTHER = sort(app.OTHER, 'ascend');  % Sort OTHER in ascending order
                        app.OTHERPress8TextArea.Value = num2str(app.OTHER);  % Update the text area value
                        fprintf('%s - current frame: %d\n', key, app.cnt_frame);  % Print the key and current frame
            
                        app.Label_OTHER.Text = sprintf('(%d)', length(app.OTHER));  % Update the label text

                    case 'j'  % If the 'j' key is pressed
                        app.ST_JNK = [app.ST_JNK app.cnt_frame];  % Add the current frame to ST_JNK
                        app.ST_JNK = sort(app.ST_JNK, 'ascend');  % Sort ST_JNK in ascending order
                        app.ST_JNKPressjTextArea.Value = num2str(app.ST_JNK);  % Update the text area value
                        fprintf('%s - current frame: %d\n', key, app.cnt_frame);  % Print the key and current frame
            
                        app.Label_ST_JNK.Text = sprintf('(%d)', length(app.ST_JNK));  % Update the label text

                    case 'k'  % If the 'J' key is pressed
                        app.END_JNK = [app.END_JNK app.cnt_frame];  % Add the current frame to END_JNK
                        app.END_JNK = sort(app.END_JNK, 'ascend');  % Sort END_JNK in ascending order
                        app.END_JNKPresskTextArea.Value = num2str(app.END_JNK);  % Update the text area value
                        fprintf('%s - current frame: %d\n', key, app.cnt_frame);  % Print the key and current frame
            
                        app.Label_END_JNK.Text = sprintf('(%d)', length(app.END_JNK));  % Update the label text
                end
            
                % Ensure the current frame stays within valid bounds
                app.cnt_frame = max(app.cnt_frame, 1);  % Prevent current frame from going below 1
                app.cnt_frame = min(app.cnt_frame, app.N_frames);  % Prevent current frame from exceeding the total number of frames
            
                app.update_image;  % Update the displayed image
            end
        end

        % Button pushed function: SaveEventsButton
        function SaveEventsButtonPushed(app, event)
            dataEvent.ST_RCH = app.ST_RCH; % Start Reach (1)
            dataEvent.HND_AT_OBJ = app.HND_AT_OBJ; % Hand at Object (2)
            dataEvent.ST_PULL = app.ST_PULL; % Start Pull (3)
            dataEvent.END_PULL = app.END_PULL; % End Pull (4)
            dataEvent.END_RLS = app.END_RLS; % Release (5)
            dataEvent.ST_HND_TO_MTH = app.ST_HND_TO_MTH; % Start Hand to Mouth (6)
            dataEvent.END_HND_TO_MTH = app.END_HND_TO_MTH; % End Hand to Mouth (7)
            dataEvent.OTHER = app.OTHER; % Other or Miscellaneous Event (8)
            dataEvent.ST_JNK = app.ST_JNK; % Start Junk Data (j)
            dataEvent.END_JNK = app.END_JNK; % End Junk Data (J)
            
            saveEvent = [app.outputPath app.filename '_events.mat'];
            
            save(saveEvent,'dataEvent')
            disp([saveEvent ' is saved'])
        end

        % Button pushed function: CorrectButton_ST_RCH
        function CorrectButton_ST_RCHPushed(app, event)
            
            cnt_index = find(app.ST_RCH == app.cnt_frame);  % Find the index of the current frame in ST_RCH
            app.ST_RCH(cnt_index) = [];  % Remove the current frame from ST_RCH
            app.ST_RCHPress1TextArea.Value = num2str(app.ST_RCH);  % Update the text area value
            disp('ST_RCH is corrected')  % Display a message indicating the correction
        
            app.Label_ST_RCH.Text = sprintf('(%d)', length(app.ST_RCH));  % Update the label text using sprintf

        end

        % Button pushed function: CorrectButton_HND_AT_OBJ
        function CorrectButton_HND_AT_OBJPushed(app, event)
            cnt_index = find(app.HND_AT_OBJ == app.cnt_frame);  % Find the index of the current frame in HND_AT_OBJ
            app.HND_AT_OBJ(cnt_index) = [];  % Remove the current frame from HND_AT_OBJ
            app.HND_AT_OBJPress2TextArea.Value = num2str(app.HND_AT_OBJ);  % Update the text area value
            disp('HND_AT_OBJ is corrected')  % Display a message indicating the correction
        
            app.Label_HND_AT_OBJ.Text = sprintf('(%d)', length(app.HND_AT_OBJ));  % Update the label text using
        end

        % Button pushed function: CorrectButton_ST_PULL
        function CorrectButton_ST_PULLPushed(app, event)

            cnt_index = find(app.ST_PULL == app.cnt_frame);  % Find the index of the current frame in ST_PULL
            app.ST_PULL(cnt_index) = [];  % Remove the current frame from ST_PULL
            app.ST_PULLPress3TextArea.Value = num2str(app.ST_PULL);  % Update the text area value
            disp('ST_PULL is corrected')  % Display a message indicating the correction
        
            app.Label_ST_PULL.Text = sprintf('(%d)', length(app.ST_PULL));  % Update the label text using sprintf
        end

        % Button pushed function: CorrectButton_END_PULL
        function CorrectButton_END_PULLPushed(app, event)

            cnt_index = find(app.END_PULL == app.cnt_frame);  % Find the index of the current frame in END_PULL
            app.END_PULL(cnt_index) = [];  % Remove the current frame from END_PULL
            app.END_PULLPress4TextArea.Value = num2str(app.END_PULL);  % Update the text area value
            disp('END_PULL is corrected')  % Display a message indicating the correction
        
            app.Label_END_PULL.Text = sprintf('(%d)', length(app.END_PULL));  % Update the label text using sprintf
        end

        % Button pushed function: CorrectButton_END_RLS
        function CorrectButton_END_RLSPushed(app, event)

            cnt_index = find(app.END_RLS == app.cnt_frame);  % Find the index of the current frame in END_RLS
            app.END_RLS(cnt_index) = [];  % Remove the current frame from END_RLS
            app.END_RLSPress5TextArea.Value = num2str(app.END_RLS);  % Update the text area value
            disp('END_RLS is corrected')  % Display a message indicating the correction
        
            app.Label_END_RLS.Text = sprintf('(%d)', length(app.END_RLS));  % Update the label text using sprintf
        end

        % Value changed function: JumptoFrameEditField
        function JumptoFrameEditFieldValueChanged(app, event)
            value = app.JumptoFrameEditField.Value;
            app.cnt_frame = value;
            app.i = ceil(app.cnt_frame/app.N);
            
            disp(['Loading frames ', num2str(1+(app.i-1)*app.N), ' to ', num2str(app.i*app.N), ' for video 1'])
            app.video1 = read(app.vidObj1, [1+(app.i-1)*app.N min(app.i*app.N, app.N_frames)]);
            
            disp(['Loading frames ', num2str(1+(app.i-1)*app.N), ' to ', num2str(app.i*app.N), ' for video 2'])
            app.video2 = read(app.vidObj2, [1+(app.i-1)*app.N min(app.i*app.N, app.N_frames)]);
            app.update_image;
        end

        % Value changed function: ZoomButton
        function ZoomButtonValueChanged(app, event)
            value = app.ZoomButton.Value;
            if value
                set(app.UIAxes_Vicon,'ylim',[150 400],'xlim',app.cnt_frame+[-10 10]*app.vidFrameRate)
            else
                set(app.UIAxes_Vicon,'ylim',[150 400],'xlim',[1 app.N_frames])
            end
        end

        % Button pushed function: CorrectButton_ST_HND_TO_MTH
        function CorrectButton_ST_HND_TO_MTHPushed(app, event)
            cnt_index = find(app.ST_HND_TO_MTH == app.cnt_frame);  % Find the index of the current frame in ST_HND_TO_MTH
            app.ST_HND_TO_MTH(cnt_index) = [];  % Remove the current frame from ST_HND_TO_MTH
            app.ST_HND_TO_MTHPress6TextArea.Value = num2str(app.ST_HND_TO_MTH);  % Update the text area value
            disp('ST_HND_TO_MTH is corrected')  % Display a message indicating the correction
        
            app.Label_ST_HND_TO_MTH.Text = sprintf('(%d)', length(app.ST_HND_TO_MTH));  % Update the label text using sprintf
        end

        % Button pushed function: CorrectButton_END_HND_TO_MTH
        function CorrectButton_END_HND_TO_MTHPushed(app, event)
            cnt_index = find(app.END_HND_TO_MTH == app.cnt_frame);  % Find the index of the current frame in END_HND_TO_MTH
            app.END_HND_TO_MTH(cnt_index) = [];  % Remove the current frame from END_HND_TO_MTH
            app.END_HND_TO_MTHPress7TextArea.Value = num2str(app.END_HND_TO_MTH);  % Update the text area value
            disp('END_HND_TO_MTH is corrected')  % Display a message indicating the correction
        
            app.Label_END_HND_TO_MTH.Text = sprintf('(%d)', length(app.END_HND_TO_MTH));  % Update the label text using sprintf
        end

        % Button pushed function: CorrectButton_OTHER
        function CorrectButton_OTHERPushed(app, event)
            cnt_index = find(app.OTHER == app.cnt_frame);  % Find the index of the current frame in OTHER
            app.OTHER(cnt_index) = [];  % Remove the current frame from OTHER
            app.OTHERPress8TextArea.Value = num2str(app.OTHER);  % Update the text area value
            disp('OTHER is corrected')  % Display a message indicating the correction
        
            app.Label_OTHER.Text = sprintf('(%d)', length(app.OTHER));  % Update the label text using sprintf
        end

        % Button pushed function: CorrectButton_ST_JNK
        function CorrectButton_ST_JNKPushed(app, event)
            cnt_index = find(app.ST_JNK == app.cnt_frame);  % Find the index of the current frame in ST_JNK
            app.ST_JNK(cnt_index) = [];  % Remove the current frame from ST_JNK
            app.ST_JNKPressjTextArea.Value = num2str(app.ST_JNK);  % Update the text area value
            disp('ST_JNK is corrected')  % Display a message indicating the correction
        
            app.Label_ST_JNK.Text = sprintf('(%d)', length(app.ST_JNK));  % Update the label text using sprintf            
        end

        % Button pushed function: CorrectButton_END_JNK
        function CorrectButton_END_JNKPushed(app, event)
            cnt_index = find(app.END_JNK == app.cnt_frame);  % Find the index of the current frame in END_JNK
            app.END_JNK(cnt_index) = [];  % Remove the current frame from END_JNK
            app.END_JNKPresskTextArea.Value = num2str(app.END_JNK);  % Update the text area value
            disp('END_JNK is corrected')  % Display a message indicating the correction
        
            app.Label_END_JNK.Text = sprintf('(%d)', length(app.END_JNK));  % Update the label text using sprintf            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes_VideoL = uiaxes(app.UIFigure);
            title(app.UIAxes_VideoL, '2nd Video')
            app.UIAxes_VideoL.XTick = [];
            app.UIAxes_VideoL.YTick = [];
            app.UIAxes_VideoR = uiaxes(app.UIFigure);
            title(app.UIAxes_VideoR, '1st Video')
            app.UIAxes_VideoR.XTick = [];
            app.UIAxes_VideoR.YTick = [];
            app.UIAxes_Vicon = uiaxes(app.UIFigure);
            app.UIAxes_Vicon.YTick = [];
            app.LoadVideosButton = uibutton(app.UIFigure, 'push');
            app.LoadVideosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideosButtonPushed, true);
            app.LoadVideosButton.Text = 'Load Videos';
            app.ST_RCHPress1TextAreaLabel = uilabel(app.UIFigure);
            app.ST_RCHPress1TextAreaLabel.Text = 'ST_RCH, Press ''1''';
            app.ST_RCHPress1TextArea = uitextarea(app.UIFigure);
            app.HND_AT_OBJPress2Label = uilabel(app.UIFigure);
            app.HND_AT_OBJPress2Label.Text = 'HND_AT_OBJ, Press ''2''';
            app.HND_AT_OBJPress2TextArea = uitextarea(app.UIFigure);
            app.ST_PULLPress3Label = uilabel(app.UIFigure);
            app.ST_PULLPress3Label.Text = 'ST_PULL, Press ''3''';
            app.ST_PULLPress3TextArea = uitextarea(app.UIFigure);
            app.RLSPress5Label = uilabel(app.UIFigure);
            app.RLSPress5Label.Text = 'END_RLS, Press ''5''';
            app.END_RLSPress5TextArea = uitextarea(app.UIFigure);
            app.END_PULLPress4TextAreaLabel = uilabel(app.UIFigure);
            app.END_PULLPress4TextAreaLabel.Text = 'END_PULL, Press ''4''';
            app.END_PULLPress4TextArea = uitextarea(app.UIFigure);
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.INSTRUCTIONS_HEADER_TEXT = uilabel(app.UIFigure);
            app.INSTRUCTIONS_HEADER_TEXT.Text = {'Instructions for frame control: rightarrow: +1,  leftarrow: -1,  '; 'uparrow: +10, downarrow: -10,  9:+100,  0:-100 '};
            app.SaveEventsButton = uibutton(app.UIFigure, 'push');
            app.SaveEventsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEventsButtonPushed, true);
            app.SaveEventsButton.Text = 'Save Events';
            app.MarkEventsButton = uibutton(app.UIFigure, 'state');
            app.MarkEventsButton.ValueChangedFcn = createCallbackFcn(app, @MarkEventsButtonValueChanged, true);
            app.MarkEventsButton.Text = 'Mark Events';
            app.CorrectButton_ST_RCH = uibutton(app.UIFigure, 'push');
            app.CorrectButton_ST_RCH.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_ST_RCHPushed, true);
            app.CorrectButton_ST_RCH.Text = 'Correct ';
            app.CorrectButton_HND_AT_OBJ = uibutton(app.UIFigure, 'push');
            app.CorrectButton_HND_AT_OBJ.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_HND_AT_OBJPushed, true);
            app.CorrectButton_HND_AT_OBJ.Text = 'Correct ';
            app.CorrectButton_ST_PULL = uibutton(app.UIFigure, 'push');
            app.CorrectButton_ST_PULL.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_ST_PULLPushed, true);
            app.CorrectButton_ST_PULL.Text = 'Correct ';
            app.CorrectButton_END_PULL = uibutton(app.UIFigure, 'push');
            app.CorrectButton_END_PULL.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_END_PULLPushed, true);
            app.CorrectButton_END_PULL.Text = 'Correct ';
            app.CorrectButton_END_RLS = uibutton(app.UIFigure, 'push');
            app.CorrectButton_END_RLS.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_END_RLSPushed, true);
            app.CorrectButton_END_RLS.Text = 'Correct ';
            app.JumptoFrameEditFieldLabel = uilabel(app.UIFigure);
            app.JumptoFrameEditFieldLabel.Text = 'Jump to Frame';
            app.JumptoFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.JumptoFrameEditField.ValueChangedFcn = createCallbackFcn(app, @JumptoFrameEditFieldValueChanged, true);
            app.JumptoFrameEditField.Value = 1;
            app.Label_ST_RCH = uilabel(app.UIFigure);
            app.Label_ST_RCH.Text = '(0)';
            app.Label_HND_AT_OBJ = uilabel(app.UIFigure);
            app.Label_HND_AT_OBJ.Text = '(0)';
            app.Label_ST_PULL = uilabel(app.UIFigure);
            app.Label_ST_PULL.Text = '(0)';
            app.Label_END_PULL = uilabel(app.UIFigure);
            app.Label_END_PULL.Text = '(0)';
            app.Label_END_RLS = uilabel(app.UIFigure);
            app.Label_END_RLS.Text = '(0)';
            app.ZoomButton = uibutton(app.UIFigure, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoomButton.Text = 'Zoom';
            app.CorrectButton_OTHER = uibutton(app.UIFigure, 'push');
            app.CorrectButton_OTHER.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_OTHERPushed, true);
            app.CorrectButton_OTHER.Text = 'Correct ';
            app.Label_OTHER = uilabel(app.UIFigure);
            app.Label_OTHER.Text = '(0)';
            app.OTHERPress8TextAreaLabel = uilabel(app.UIFigure);
            app.OTHERPress8TextAreaLabel.Text = 'OTHER, Press ''8''';
            app.OTHERPress8TextArea = uitextarea(app.UIFigure);
            app.CorrectButton_END_HND_TO_MTH = uibutton(app.UIFigure, 'push');
            app.CorrectButton_END_HND_TO_MTH.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_END_HND_TO_MTHPushed, true);
            app.CorrectButton_END_HND_TO_MTH.Text = 'Correct ';
            app.Label_END_HND_TO_MTH = uilabel(app.UIFigure);
            app.Label_END_HND_TO_MTH.Text = '(0)';
            app.END_HND_TO_MTHPress7TextAreaLabel = uilabel(app.UIFigure);
            app.END_HND_TO_MTHPress7TextAreaLabel.Text = 'END_HND_TO_MTH, Press ''7''';
            app.END_HND_TO_MTHPress7TextArea = uitextarea(app.UIFigure);
            app.CorrectButton_ST_HND_TO_MTH = uibutton(app.UIFigure, 'push');
            app.CorrectButton_ST_HND_TO_MTH.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_ST_HND_TO_MTHPushed, true);
            app.CorrectButton_ST_HND_TO_MTH.Text = 'Correct ';
            app.Label_ST_HND_TO_MTH = uilabel(app.UIFigure);
            app.Label_ST_HND_TO_MTH.Text = '(0)';
            app.ST_HND_TO_MTHPress6TextAreaLabel = uilabel(app.UIFigure);
            app.ST_HND_TO_MTHPress6TextAreaLabel.Text = 'ST_HND_TO_MTH, Press ''6''';
            app.ST_HND_TO_MTHPress6TextArea = uitextarea(app.UIFigure);
            app.CorrectButton_END_JNK = uibutton(app.UIFigure, 'push');
            app.CorrectButton_END_JNK.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_END_JNKPushed, true);
            app.CorrectButton_END_JNK.Text = 'Correct ';
            app.Label_END_JNK = uilabel(app.UIFigure);
            app.Label_END_JNK.Text = '(0)';
            app.END_JNKPresskTextAreaLabel = uilabel(app.UIFigure);
            app.END_JNKPresskTextAreaLabel.Text = 'END_JNK, Press ''k''';
            app.END_JNKPresskTextArea = uitextarea(app.UIFigure);
            app.CorrectButton_ST_JNK = uibutton(app.UIFigure, 'push');
            app.CorrectButton_ST_JNK.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_ST_JNKPushed, true);
            app.CorrectButton_ST_JNK.Text = 'Correct ';
            app.Label_ST_JNK = uilabel(app.UIFigure);
            app.Label_ST_JNK.Text = '(0)';
            app.ST_JNKPressjLabel = uilabel(app.UIFigure);
            app.ST_JNKPressjLabel.Text = 'ST_JNK, Press ''j''';
            app.ST_JNKPressjTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MarkEventsVideo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end