classdef MarkEventsVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ST_HND_TO_MTHPress6TextArea   matlab.ui.control.TextArea
        ST_HND_TO_MTHPress6TextAreaLabel  matlab.ui.control.Label
        Label_ST_HND_TO_MTH           matlab.ui.control.Label
        CorrectButton_ST_HND_TO_MTH   matlab.ui.control.Button
        END_HND_TO_MTHPress7TextArea  matlab.ui.control.TextArea
        END_HND_TO_MTHPress7TextAreaLabel  matlab.ui.control.Label
        Label_END_HND_TO_MTH          matlab.ui.control.Label
        CorrectButton_END_HND_TO_MTH  matlab.ui.control.Button
        OTHERPress8TextArea           matlab.ui.control.TextArea
        OTHERPress8TextAreaLabel      matlab.ui.control.Label
        Label_OTHER                   matlab.ui.control.Label
        CorrectButton_OTHER           matlab.ui.control.Button
        ZoomButton                    matlab.ui.control.StateButton
        Label_END_RLS                 matlab.ui.control.Label
        Label_END_PULL                matlab.ui.control.Label
        Label_ST_PULL                 matlab.ui.control.Label
        Label_HAND_AT_OBJ             matlab.ui.control.Label
        Label_ST_RCH                  matlab.ui.control.Label
        JumptoFrameEditField          matlab.ui.control.NumericEditField
        JumptoFrameEditFieldLabel     matlab.ui.control.Label
        CorrectButton_END_RLS         matlab.ui.control.Button
        CorrectButton_END_PULL        matlab.ui.control.Button
        CorrectButton_ST_PULL         matlab.ui.control.Button
        CorrectButton_HAND_AT_OBJ     matlab.ui.control.Button
        CorrectButton_ST_RCH          matlab.ui.control.Button
        MarkEventsButton              matlab.ui.control.StateButton
        SaveEventsButton              matlab.ui.control.Button
        INSTRUCTIONS_HEADER_TEXT      matlab.ui.control.Label
        PlayButton                    matlab.ui.control.StateButton
        END_PULLPress4TextArea        matlab.ui.control.TextArea
        END_PULLPress4TextAreaLabel   matlab.ui.control.Label
        END_RLSPress5TextArea         matlab.ui.control.TextArea
        RLSPress5Label                matlab.ui.control.Label
        ST_PULLPress3TextArea         matlab.ui.control.TextArea
        ST_PULLPress3Label            matlab.ui.control.Label
        HND_AT_OBJPress2TextArea      matlab.ui.control.TextArea
        HND_AT_OBJPress2Label         matlab.ui.control.Label
        ST_RCHPress1TextArea          matlab.ui.control.TextArea
        ST_RCHPress1TextAreaLabel     matlab.ui.control.Label
        LoadVideosButton              matlab.ui.control.Button
        UIAxes_Vicon                  matlab.ui.control.UIAxes
        UIAxes_VideoR                 matlab.ui.control.UIAxes
        UIAxes_VideoL                 matlab.ui.control.UIAxes
    end

    
properties (Access = private)
    vidObj1 % Video object for the first video
    vidObj2 % Video object for the second video
    PathSession % Path for the session data
    PathProcessed % Path for the processed data
    N_frames % Number of frames in the video
    N % Number of frames to load at once
    brightLev % Brightness level for displaying images
    filename % Filename for saving or loading data
    eventMarked % Indicator for events marked in the video
    i % Index for loading frames
    fr % Frame number within the current batch
    start_frame % Starting frame for processing
    cnt_frame % Counter for the current frame
    video1 % Data for the first video
    video2 % Data for the second video
    pick_index % Index for picking a specific frame or event
    pickPosition % Position for picking a specific frame or event
    simiMat % Similarity matrix for some processing
    h_cntFrame % Handle for the current frame counter visualization
    vidFrameRate % Frame rate of the video
    kinFrameRate % Frame rate of the kinematic data
end

methods (Access = private)

    function update_image(app)
        % Check if the current frame counter exceeds the current batch of frames
        if app.cnt_frame > app.i * app.N
            app.i = app.i + 1; % Increment the batch index
            fprintf('Loading frames %d to %d for video 1\n', 1 + (app.i-1) * app.N, app.i * app.N)
            % Load frames for video 1
            app.video1 = read(app.vidObj1, [1 + (app.i-1) * app.N, min(app.i * app.N, app.N_frames)]);
            
            fprintf('Loading frames %d to %d for video 2\n', 1 + (app.i-1) * app.N, app.i * app.N)
            % Load frames for video 2
            app.video2 = read(app.vidObj2, [1 + (app.i-1) * app.N, min(app.i * app.N, app.N_frames)]);
        elseif app.cnt_frame <= (app.i-1) * app.N
            app.i = app.i - 1; % Decrement the batch index
            fprintf('Loading frames %d to %d for video 1\n', 1 + (app.i-1) * app.N, app.i * app.N)
            % Load frames for video 1
            app.video1 = read(app.vidObj1, [1 + (app.i-1) * app.N, min(app.i * app.N, app.N_frames)]);
            
            fprintf('Loading frames %d to %d for video 2\n', 1 + (app.i-1) * app.N, app.i * app.N)
            % Load frames for video 2
            app.video2 = read(app.vidObj2, [1 + (app.i-1) * app.N, min(app.i * app.N, app.N_frames)]);
        end
        app.fr = app.cnt_frame - (app.i-1) * app.N; % Calculate the current frame within the batch
        
        % Display the current frame of video 1 with adjusted brightness
        imshow(app.video1(:,:,:,app.fr) * app.brightLev, 'Parent', app.UIAxes_VideoL);
        app.UIAxes_VideoL.Title.String = sprintf('Left - frame %d / %d', app.cnt_frame, app.N_frames);
        
        % Display the current frame of video 2 with adjusted brightness
        imshow(app.video2(:,:,:,app.fr) * app.brightLev, 'Parent', app.UIAxes_VideoR);
        app.UIAxes_VideoR.Title.String = sprintf('Right - frame %d / %d', app.cnt_frame, app.N_frames);
        
        % Update the position of the frame counter visualization
        set(app.h_cntFrame, 'XData', app.cnt_frame * [1 1])
    end
end

methods (Access = public)
    
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc  % Clear the command window
            
            % Display a message to instruct the user to load the videos
            disp('Please Load the videos')
            
            % Initialize the starting frame for processing
            app.start_frame = 1;
            
            % Set the current frame counter to the starting frame
            app.cnt_frame = app.start_frame;
            
            % Initialize the index for picking a specific frame or event
            app.pick_index = 0;
        end

        % Button pushed function: LoadVideosButton
        function LoadVideosButtonPushed(app, event)
            % Read video files
            [filenm, path, FilterIndex] = uigetfile('*','Select the 1st video file');
            if FilterIndex == 0
                disp('Error in the video file loading') % Display error if no file is selected
                return
            else
                disp('1st video file has been successfully loaded') % Display success message
            end
            cd(path) % Change directory to the path of the first video
            app.vidObj1 = VideoReader(filenm); % Create a VideoReader object for the first video
            app.vidFrameRate = app.vidObj1.FrameRate; % Get the frame rate of the first video
            
            [filenm, path, FilterIndex] = uigetfile('*','Select the 2nd video file');
            if FilterIndex == 0
                disp('Error in the video file loading') % Display error if no file is selected
                return
            else
                disp('2nd video file has been successfully loaded') % Display success message
            end
            app.vidObj2 = VideoReader(filenm); % Create a VideoReader object for the second video
            
            [filenm, path, FilterIndex] = uigetfile('*','Select the simi matlab file');
            if FilterIndex == 0
                disp('Error in the video file loading') % Display error if no file is selected
                return
            else
                disp('simi matlab file has been successfully loaded') % Display success message
            end
            app.simiMat = load(filenm); % Load the simi matlab file
            
            % Path Info
            slashLoc = find(path == filesep); % Find the location of file separators in the path
            app.PathSession = path(1:slashLoc(end-1)); % Path to the current session
            app.PathProcessed = [path 'ProcessedData' filesep]; % Path to the processed data
            if ~exist(app.PathProcessed, 'dir')
                mkdir(app.PathProcessed); % Create the processed data directory if it does not exist
            end
            
            app.N_frames = floor(app.vidObj1.Duration * app.vidObj1.FrameRate); % Calculate the number of frames in the video
            app.N = 500;
            
            % LEGACY:   Originally set variable app.N for speed, but set to
            %           the same value.
            % FIX:      Declaring `app.N=500;` globally.Mark
            % if isunix % Check if the system is Unix-based
            %     app.N = 500; % Number of frames loaded at once (500 for Unix)
            % else
            %     app.N = 500; % Number of frames loaded at once (500 for Windows)
            % end
            
            app.brightLev = 1; % Set the brightness level
            app.filename = filenm(1:end-4); % Set the filename without extension
            
            % Display the first frame images
            app.i = 1; % Initialize the frame batch index
            app.fr = 1; % Initialize the frame index within the batch
            
            disp(['Loading frames ', num2str(1 + (app.i-1) * app.N), ' to ', num2str(app.i * app.N), ' for video 1'])
            app.video1 = read(app.vidObj1, [1 + (app.i-1) * app.N, min(app.i * app.N, app.N_frames)]); % Load frames for video 1
            
            imshow(app.video1(:,:,:,app.fr) * app.brightLev, 'Parent', app.UIAxes_VideoL); % Display the first frame of video 1 with adjusted brightness
            app.UIAxes_VideoL.Title.String = ['Left - frame ' num2str(app.fr)]; % Set the title for the left video axes
            
            disp(['Loading frames ', num2str(1 + (app.i-1) * app.N), ' to ', num2str(app.i * app.N), ' for video 2'])
            app.video2 = read(app.vidObj2, [1 + (app.i-1) * app.N, min(app.i * app.N, app.N_frames)]); % Load frames for video 2
            
            imshow(app.video2(:,:,:,app.fr) * app.brightLev, 'Parent', app.UIAxes_VideoR); % Display the first frame of video 2 with adjusted brightness
            app.UIAxes_VideoR.Title.String = ['Right - frame ' num2str(app.fr)]; % Set the title for the right video axes
            
            % Plot WRB signal and robot signal
            simiMat = app.simiMat; % Load simiMat data
            dataRfoot = simiMat.KIN_3D.rightFifthMetatarsalMT.z; % Extract right foot data
            dataLfoot = simiMat.KIN_3D.leftFifthMetatarsalMT.z; % Extract left foot data
            app.kinFrameRate = 100; % Set the kinematic data frame rate
            
            hold(app.UIAxes_Vicon, 'on') % Hold the current plot
            plot(app.UIAxes_Vicon, [1:length(dataLfoot)] / app.kinFrameRate * app.vidFrameRate, dataLfoot, '-b') % Plot left foot data
            plot(app.UIAxes_Vicon, [1:length(dataRfoot)] / app.kinFrameRate * app.vidFrameRate, dataRfoot, '-r') % Plot right foot data
            app.h_cntFrame = plot(app.UIAxes_Vicon, app.cnt_frame * [1 1], [0 0.15], ':k'); % Plot current frame indicator
            set(app.UIAxes_Vicon, 'ylim', [0 0.15]) % Set y-axis limits
            
            % Reset or load events
            backupEvent = [app.PathProcessed app.filename '_events.mat'];
            if exist(backupEvent, 'file') ~= 0
                choice = questdlg('An events file with the same name already exists, what do you want to do?', ...
                                  'There is already an file for this acquisition', ...
                                  'Start from scratch', 'Start with those previous events', 'Start from scratch');
                % Handle response
                switch choice
                    case 'Start with those previous events'
                        load(backupEvent); % Load previous events
                        disp('Previous events have been loaded')
                        app.eventMarked.LFO = dataEvent.LFO;
                        app.eventMarked.LFS = dataEvent.LFS;
                        app.eventMarked.RFO = dataEvent.RFO;
                        app.eventMarked.OTHER = dataEvent.other;
                        app.eventMarked.RFS = dataEvent.RFS;
                    case 'Start from scratch'
                        % Initialize empty events
                        app.eventMarked.LFO = []; % Beginning of movement
                        app.eventMarked.LFS = []; % Grasping
                        app.eventMarked.RFO = []; % Light off or time mark leaving the object
                        app.eventMarked.OTHER = []; % Successful trial indicator
                        app.eventMarked.RFS = [];
                end
            else
                % Initialize empty events
                app.eventMarked.LFO = []; % Beginning of movement
                app.eventMarked.LFS = []; % Grasping
                app.eventMarked.RFO = []; % Light off or time mark leaving the object
                app.eventMarked.OTHER = []; % Successful trial indicator
                app.eventMarked.RFS = [];
            end
            
            % Update event text areas and labels
            app.ST_RCHPress1TextArea.Value = num2str(app.eventMarked.LFO);
            app.Label_ST_RCH.Text = ['(' num2str(length(app.eventMarked.LFO)) ')'];
            
            app.HND_AT_OBJPress2TextArea.Value = num2str(app.eventMarked.LFS);
            app.Label_HAND_AT_OBJ.Text = ['(' num2str(length(app.eventMarked.LFS)) ')'];
            
            app.ST_PULLPress3TextArea.Value = num2str(app.eventMarked.RFO);
            app.Label_ST_PULL.Text = ['(' num2str(length(app.eventMarked.RFO)) ')'];
            
            app.END_RLSPress5TextArea.Value = num2str(app.eventMarked.OTHER);
            app.Label_END_RLS.Text = ['(' num2str(length(app.eventMarked.OTHER)) ')'];
            
            app.END_PULLPress4TextArea.Value = num2str(app.eventMarked.RFS);
            app.Label_END_PULL.Text = ['(' num2str(length(app.eventMarked.RFS)) ')'];
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value == 1
                disp('Start to play the videos, press to stop')
                app.PlayButton.Text = 'STOP Play';
                while value && app.cnt_frame<=app.N_frames
                    app.cnt_frame = app.cnt_frame+1;
                    app.update_image;
                    
                    value = app.PlayButton.Value;
                    pause(0.5)
                end
            else
                disp('Stopped the videos, press to start')
                app.PlayButton.Text = 'Play';
            end
        end

        % Value changed function: MarkEventsButton
        function MarkEventsButtonValueChanged(app, event)
            value = app.MarkEventsButton.Value;
            
            if value
                disp('Start changing image frame with keyboard')
                
            else
                disp('Stop changing image frame with keyboard')
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if app.MarkEventsButton.Value
                switch key
                    case 'leftarrow'
                        app.cnt_frame = app.cnt_frame - 1;
                    case 'rightarrow'
                        app.cnt_frame = app.cnt_frame + 1;
                    case 'downarrow'
                        app.cnt_frame = app.cnt_frame - 10;
                    case 'uparrow'
                        app.cnt_frame = app.cnt_frame + 10;
                    case '9'
                        app.cnt_frame = app.cnt_frame + 100;
                    case '0'
                        app.cnt_frame = app.cnt_frame - 100;
                        
                    case '1'
                        app.eventMarked.LFO = [app.eventMarked.LFO app.cnt_frame];
                        app.eventMarked.LFO = sort(app.eventMarked.LFO,'ascend');
                        app.ST_RCHPress1TextArea.Value = num2str(app.eventMarked.LFO);
                        disp([key ' - current frame:' num2str(app.cnt_frame)])

                        app.Label_ST_RCH.Text = ['(' num2str(length(app.eventMarked.LFO)) ')'];
                    case '2'
                        app.eventMarked.LFS = [app.eventMarked.LFS app.cnt_frame];
                        app.eventMarked.LFS = sort(app.eventMarked.LFS,'ascend');
                        app.HND_AT_OBJPress2TextArea.Value = num2str(app.eventMarked.LFS);
                        disp([key ' - current frame:' num2str(app.cnt_frame)])

                        app.Label_HAND_AT_OBJ.Text = ['(' num2str(length(app.eventMarked.LFS)) ')'];
                    case '3'
                        app.eventMarked.RFO = [app.eventMarked.RFO app.cnt_frame];
                        app.eventMarked.RFO = sort(app.eventMarked.RFO,'ascend');
                        app.ST_PULLPress3TextArea.Value = num2str(app.eventMarked.RFO);
                        disp([key ' - current frame:' num2str(app.cnt_frame)])

                        app.Label_ST_PULL.Text = ['(' num2str(length(app.eventMarked.RFO)) ')'];
                    case '4'
                        app.eventMarked.RFS = [app.eventMarked.RFS app.cnt_frame];
                        app.eventMarked.RFS = sort(app.eventMarked.RFS,'ascend');
                        app.END_PULLPress4TextArea.Value = num2str(app.eventMarked.RFS);
                        disp([key ' - current frame:' num2str(app.cnt_frame)])

                        app.Label_END_PULL.Text = ['(' num2str(length(app.eventMarked.RFS)) ')'];
                    case '5'
                        app.eventMarked.OTHER = [app.eventMarked.OTHER app.cnt_frame];
                        app.eventMarked.OTHER = sort(app.eventMarked.OTHER,'ascend');
                        app.END_RLSPress5TextArea.Value = num2str(app.eventMarked.OTHER);
                        disp([key ' - current frame:' num2str(app.cnt_frame)])

                        app.Label_END_RLS.Text = ['(' num2str(length(app.eventMarked.OTHER)) ')'];
                        
                        
                end
                app.cnt_frame = max(app.cnt_frame,1);
                app.cnt_frame = min(app.cnt_frame,app.N_frames);
                
                app.update_image;
            end
        end

        % Button pushed function: SaveEventsButton
        function SaveEventsButtonPushed(app, event)
            dataEvent.LFO=app.eventMarked.LFO;
            dataEvent.LFS=app.eventMarked.LFS;
            dataEvent.RFO=app.eventMarked.RFO;
            dataEvent.RFS = app.eventMarked.RFS;
            dataEvent.other=app.eventMarked.OTHER;
            
            saveEvent = [app.PathProcessed app.filename '_events.mat'];
            
            save(saveEvent,'dataEvent')
            disp([saveEvent ' is saved'])
        end

        % Button pushed function: CorrectButton_ST_RCH
        function CorrectButton_ST_RCHPushed(app, event)
           
            cnt_index = find(app.eventMarked.LFO==app.cnt_frame);
            app.eventMarked.LFO(cnt_index) = [];
            app.ST_RCHPress1TextArea.Value = num2str(app.eventMarked.LFO);
            disp('LFO is corrected')

            app.Label_ST_RCH.Text = ['(' num2str(length(app.eventMarked.LFO)) ')'];
        end

        % Button pushed function: CorrectButton_HAND_AT_OBJ
        function CorrectButton_HAND_AT_OBJPushed(app, event)
           
            cnt_index = find(app.eventMarked.LFS==app.cnt_frame);
            app.eventMarked.LFS(cnt_index) = [];
            app.HND_AT_OBJPress2TextArea.Value = num2str(app.eventMarked.LFS);
            disp('LFS is corrected')

            app.Label_HAND_AT_OBJ.Text = ['(' num2str(length(app.eventMarked.LFS)) ')'];
        end

        % Button pushed function: CorrectButton_ST_PULL
        function CorrectButton_ST_PULLPushed(app, event)
            
            cnt_index = find(app.eventMarked.RFO==app.cnt_frame);
            app.eventMarked.RFO(cnt_index) = [];
            app.ST_PULLPress3TextArea.Value = num2str(app.eventMarked.RFO);
            disp('RFO is corrected')

            app.Label_ST_PULL.Text = ['(' num2str(length(app.eventMarked.RFO)) ')'];
        end

        % Button pushed function: CorrectButton_END_PULL
        function CorrectButton_END_PULLPushed(app, event)
            
            cnt_index = find(app.eventMarked.RFS(:,2)==app.cnt_frame);
            app.eventMarked.RFS(cnt_index,:) = [];
            app.END_PULLPress4TextArea.Value = num2str(app.eventMarked.RFS');

            disp('RFS is corrected')

            app.Label_END_PULL.Text = ['(' num2str(length(app.eventMarked.RFS)) ')'];
        end

        % Button pushed function: CorrectButton_END_RLS
        function CorrectButton_END_RLSPushed(app, event)
            
            cnt_index = find(app.eventMarked.OTHER(:,1)==app.cnt_frame);
            app.eventMarked.OTHER(cnt_index,:) = [];
            app.END_RLSPress5TextArea.Value = num2str(app.eventMarked.OTHER');
            disp('OTHER is corrected')

            app.Label_END_RLS.Text = ['(' num2str(length(app.eventMarked.OTHER)) ')'];
        end

        % Value changed function: JumptoFrameEditField
        function JumptoFrameEditFieldValueChanged(app, event)
            value = app.JumptoFrameEditField.Value;
            app.cnt_frame = value;
            app.i = ceil(app.cnt_frame/app.N);
            
            disp(['Loading frames ', num2str(1+(app.i-1)*app.N), ' to ', num2str(app.i*app.N), ' for video 1'])
            app.video1 = read(app.vidObj1, [1+(app.i-1)*app.N min(app.i*app.N, app.N_frames)]);
            
            disp(['Loading frames ', num2str(1+(app.i-1)*app.N), ' to ', num2str(app.i*app.N), ' for video 2'])
            app.video2 = read(app.vidObj2, [1+(app.i-1)*app.N min(app.i*app.N, app.N_frames)]);
            app.update_image;
        end

        % Value changed function: ZoomButton
        function ZoomButtonValueChanged(app, event)
            value = app.ZoomButton.Value;
            if value
                set(app.UIAxes_Vicon,'ylim',[150 400],'xlim',app.cnt_frame+[-10 10]*app.vidFrameRate)
            else
                set(app.UIAxes_Vicon,'ylim',[150 400],'xlim',[1 app.N_frames])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes_VideoL = uiaxes(app.UIFigure);
            title(app.UIAxes_VideoL, '2nd Video')
            app.UIAxes_VideoL.XTick = [];
            app.UIAxes_VideoL.YTick = [];
            app.UIAxes_VideoR = uiaxes(app.UIFigure);
            title(app.UIAxes_VideoR, '1st Video')
            app.UIAxes_VideoR.XTick = [];
            app.UIAxes_VideoR.YTick = [];
            app.UIAxes_Vicon = uiaxes(app.UIFigure);
            app.UIAxes_Vicon.YTick = [];
            app.LoadVideosButton = uibutton(app.UIFigure, 'push');
            app.LoadVideosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideosButtonPushed, true);
            app.LoadVideosButton.Text = 'Load Videos';
            app.ST_RCHPress1TextAreaLabel = uilabel(app.UIFigure);
            app.ST_RCHPress1TextAreaLabel.Text = 'ST_RCH, Press ''1''';
            app.ST_RCHPress1TextArea = uitextarea(app.UIFigure);
            app.HND_AT_OBJPress2Label = uilabel(app.UIFigure);
            app.HND_AT_OBJPress2Label.Text = 'HND_AT_OBJ, Press ''2''';
            app.HND_AT_OBJPress2TextArea = uitextarea(app.UIFigure);
            app.ST_PULLPress3Label = uilabel(app.UIFigure);
            app.ST_PULLPress3Label.Text = 'ST_PULL, Press ''3''';
            app.ST_PULLPress3TextArea = uitextarea(app.UIFigure);
            app.RLSPress5Label = uilabel(app.UIFigure);
            app.RLSPress5Label.Text = 'END_RLS, Press ''5''';
            app.END_RLSPress5TextArea = uitextarea(app.UIFigure);
            app.END_PULLPress4TextAreaLabel = uilabel(app.UIFigure);
            app.END_PULLPress4TextAreaLabel.Text = 'END_PULL, Press ''4''';
            app.END_PULLPress4TextArea = uitextarea(app.UIFigure);
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.INSTRUCTIONS_HEADER_TEXT = uilabel(app.UIFigure);
            app.INSTRUCTIONS_HEADER_TEXT.Text = {'Instructions for frame control: rightarrow: +1,  leftarrow: -1,  '; 'uparrow: +10, downarrow: -10,  9:+100,  0:-100 '};
            app.SaveEventsButton = uibutton(app.UIFigure, 'push');
            app.SaveEventsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEventsButtonPushed, true);
            app.SaveEventsButton.Text = 'Save Events';
            app.MarkEventsButton = uibutton(app.UIFigure, 'state');
            app.MarkEventsButton.ValueChangedFcn = createCallbackFcn(app, @MarkEventsButtonValueChanged, true);
            app.MarkEventsButton.Text = 'Mark Events';
            app.CorrectButton_ST_RCH = uibutton(app.UIFigure, 'push');
            app.CorrectButton_ST_RCH.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_ST_RCHPushed, true);
            app.CorrectButton_ST_RCH.Text = 'Correct ';
            app.CorrectButton_HAND_AT_OBJ = uibutton(app.UIFigure, 'push');
            app.CorrectButton_HAND_AT_OBJ.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_HAND_AT_OBJPushed, true);
            app.CorrectButton_HAND_AT_OBJ.Text = 'Correct ';
            app.CorrectButton_ST_PULL = uibutton(app.UIFigure, 'push');
            app.CorrectButton_ST_PULL.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_ST_PULLPushed, true);
            app.CorrectButton_ST_PULL.Text = 'Correct ';
            app.CorrectButton_END_PULL = uibutton(app.UIFigure, 'push');
            app.CorrectButton_END_PULL.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_END_PULLPushed, true);
            app.CorrectButton_END_PULL.Text = 'Correct ';
            app.CorrectButton_END_RLS = uibutton(app.UIFigure, 'push');
            app.CorrectButton_END_RLS.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_END_RLSPushed, true);
            app.CorrectButton_END_RLS.Text = 'Correct ';
            app.JumptoFrameEditFieldLabel = uilabel(app.UIFigure);
            app.JumptoFrameEditFieldLabel.Text = 'Jump to Frame';
            app.JumptoFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.JumptoFrameEditField.ValueChangedFcn = createCallbackFcn(app, @JumptoFrameEditFieldValueChanged, true);
            app.JumptoFrameEditField.Value = 1;
            app.Label_ST_RCH = uilabel(app.UIFigure);
            app.Label_ST_RCH.Text = '(0)';
            app.Label_HAND_AT_OBJ = uilabel(app.UIFigure);
            app.Label_HAND_AT_OBJ.Text = '(0)';
            app.Label_ST_PULL = uilabel(app.UIFigure);
            app.Label_ST_PULL.Text = '(0)';
            app.Label_END_PULL = uilabel(app.UIFigure);
            app.Label_END_PULL.Text = '(0)';
            app.Label_END_RLS = uilabel(app.UIFigure);
            app.Label_END_RLS.Text = '(0)';
            app.ZoomButton = uibutton(app.UIFigure, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoomButton.Text = 'Zoom';
            app.CorrectButton_OTHER = uibutton(app.UIFigure, 'push');
            app.CorrectButton_OTHER.Text = 'Correct ';
            app.Label_OTHER = uilabel(app.UIFigure);
            app.Label_OTHER.Text = '(0)';
            app.OTHERPress8TextAreaLabel = uilabel(app.UIFigure);
            app.OTHERPress8TextAreaLabel.Text = 'OTHER, Press ''8''';
            app.OTHERPress8TextArea = uitextarea(app.UIFigure);
            app.CorrectButton_END_HND_TO_MTH = uibutton(app.UIFigure, 'push');
            app.CorrectButton_END_HND_TO_MTH.Text = 'Correct ';
            app.Label_END_HND_TO_MTH = uilabel(app.UIFigure);
            app.Label_END_HND_TO_MTH.Text = '(0)';
            app.END_HND_TO_MTHPress7TextAreaLabel = uilabel(app.UIFigure);
            app.END_HND_TO_MTHPress7TextAreaLabel.Text = 'END_HND_TO_MTH, Press ''7''';
            app.END_HND_TO_MTHPress7TextArea = uitextarea(app.UIFigure);
            app.CorrectButton_ST_HND_TO_MTH = uibutton(app.UIFigure, 'push');
            app.CorrectButton_ST_HND_TO_MTH.Text = 'Correct ';
            app.Label_ST_HND_TO_MTH = uilabel(app.UIFigure);
            app.Label_ST_HND_TO_MTH.Text = '(0)';
            app.ST_HND_TO_MTHPress6TextAreaLabel = uilabel(app.UIFigure);
            app.ST_HND_TO_MTHPress6TextAreaLabel.Text = 'ST_HND_TO_MTH, Press ''6''';
            app.ST_HND_TO_MTHPress6TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MarkEventsVideo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end